classdef projekt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        imgOriginalLabel   matlab.ui.control.Label
        imgSegmentedLabel  matlab.ui.control.Label
        loadButton         matlab.ui.control.Button
        saveButton         matlab.ui.control.Button
        ParametrKLabel     matlab.ui.control.Label
        ParamSlider        matlab.ui.control.Slider
        SegmentButton      matlab.ui.control.Button
        UIimgSegmented     matlab.ui.control.UIAxes
        UIImgOriginal      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        imgOriginal % matrix that stores original image
        imgSegmented % matrix for segmentation operations
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadButton
        function LoadImage(app, event)
            [filename, filepath] = uigetfile({'*.jpg';'*.jpeg';'*.png'}, 'Select File to Open');
            if ~isequal(filename,0) && ~isequal(filepath,0)
                fullname = [filepath, filename];
                 app.imgOriginal = double(imread(fullname))/255;               
%                 app.imgOriginal = imread(fullname);
                imshow(app.imgOriginal, 'Parent', app.UIImgOriginal);
                cla(app.UIimgSegmented);
            end
        end

        % Button pushed function: saveButton
        function SaveImage(app, event)
            [filename, filepath] = uiputfile({'*.jpg';'*.jpeg';'*.png'}, 'Select Folder to Save');
            if ~isequal(filename,0) && ~isequal(filepath,0)
                newfilename = fullfile(filepath, filename);
                imwrite(app.imgSegmented, newfilename);
            end
        end

        % Value changed function: ParamSlider
        function ChangeParameter(app, event)
%             value = app.ParamSlider.Value;
            
        end

        % Button pushed function: SegmentButton
        function Segmentation(app, event)
            if ~isempty(app.imgOriginal)
% %                 VERSION 1 (matlab imsegkmeans function)  
%                 k = int64(app.ParamSlider.Value);
%                 [L,C] = imsegkmeans(app.imgOriginal,k);
%                 app.imgSegmented = label2rgb(L,im2double(C));

%                 VERSION 2 (copied)
%                 I = im2double(app.imgOriginal);                    % Load Image
%                 F = reshape(I,size(I,1)*size(I,2),3);                 % Color Features
%                 %% K-means
%                 K     = int64(app.ParamSlider.Value);                                            % Cluster Numbers
%                 CENTS = F( ceil(rand(K,1)*size(F,1)) ,:);             % Cluster Centers
%                 DAL   = zeros(size(F,1),K+2);                         % Distances and Labels
%                 KMI   = 10;                                           % K-means Iteration
%                 for n = 1:KMI
%                    for i = 1:size(F,1)
%                       for j = 1:K  
%                         DAL(i,j) = norm(F(i,:) - CENTS(j,:));      
%                       end
%                       [Distance, CN] = min(DAL(i,1:K));               % 1:K are Distance from Cluster Centers 1:K 
%                       DAL(i,K+1) = CN;                                % K+1 is Cluster Label
%                       DAL(i,K+2) = Distance;                          % K+2 is Minimum Distance
%                    end
%                    for i = 1:K
%                       A = (DAL(:,K+1) == i);                          % Cluster K Points
%                       CENTS(i,:) = mean(F(A,:));                      % New Cluster Centers
%                       if sum(isnan(CENTS(:))) ~= 0                    % If CENTS(i,:) Is Nan Then Replace It With Random Point
%                          NC = find(isnan(CENTS(:,1)) == 1);           % Find Nan Centers
%                          for Ind = 1:size(NC,1)
%                          CENTS(NC(Ind),:) = F(randi(size(F,1)),:);
%                          end
%                       end
%                    end
%                 end
%                 X = zeros(size(F));
%                 for i = 1:K
%                 idx = find(DAL(:,K+1) == i);
%                 X(idx,:) = repmat(CENTS(i,:),size(idx,1),1); 
%                 end
%                 app.imgSegmented = reshape(X,size(I,1),size(I,2),3);
                
%                 VERSION 3(altered version 2)
                [height, width] = size(app.imgOriginal, 1, 2);
                pixelCount = height*width;
                
                imgFlat = reshape(app.imgOriginal,pixelCount,3);         % Color Features
                K = int64(app.ParamSlider.Value);                        % Cluster Numbers
                centroids = imgFlat(randi(pixelCount, K, 1),:);          % Cluster Centers
                distances = zeros(pixelCount,K);                         % Distances
                labels = zeros(pixelCount, 1);                           % Labels
%                 iterMax = 5;                                             % K-means Iteration
                
                centroidsSet = false;
%                 for iter = 1:iterMax
                while ~centroidsSet
                   centroidsPrev = centroids;
                   for pixel = 1:pixelCount
                      for k = 1:K  
                        distances(pixel,k) = norm(imgFlat(pixel,:) - centroids(k,:));      
                      end
                      [~, label] = min(distances(pixel,1:K));            % 1:K are Distance from Cluster Centers 1:K 
                      labels(pixel) = label;                             % K+1 is Cluster Label
                   end
                   
                   for k = 1:K
                      A = (labels == k);                                 % Cluster K Points
                      centroids(k,:) = mean(imgFlat(A,:));               % New Cluster Centers
%                       if sum(isnan(CENTS(:))) ~= 0                     % If CENTS(i,:) Is Nan Then Replace It With Random Point
%                          NC = find(isnan(CENTS(:,1)) == 1);            % Find Nan Centers
%                          for Ind = 1:size(NC,1)
%                          CENTS(NC(Ind),:) = F(randi(size(F,1)),:);
%                          end
%                       end
                   end
                   centroidsSet = centroidsPrev == centroids;
                end
                
                imgSegFlat = zeros(pixelCount, 3);
                for k = 1:K
                    clusterPixels = find(labels == k);
                    imgSegFlat(clusterPixels,:) = repmat(centroids(k,:),size(clusterPixels,1),1); 
                end
                
                app.imgSegmented = reshape(imgSegFlat,height,width,3);
                imshow(app.imgSegmented, 'Parent', app.UIimgSegmented);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Segmentacja barwna metodą k-średnich';
            app.imgOriginalLabel = uilabel(app.UIFigure);
            app.imgOriginalLabel.Text = 'Oryginalny obraz';
            app.imgSegmentedLabel = uilabel(app.UIFigure);
            app.imgSegmentedLabel.Text = 'Obraz po segmentacji barwnej';
            app.loadButton = uibutton(app.UIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImage, true);
            app.loadButton.Text = 'Wczytaj obraz';
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImage, true);
            app.saveButton.Text = 'Zapisz obraz';
            app.ParametrKLabel = uilabel(app.UIFigure);
            app.ParametrKLabel.Text = 'Parametr K';
            app.ParamSlider = uislider(app.UIFigure);
            app.ParamSlider.Limits = [1 30];
            app.ParamSlider.MajorTicks = [1 10 20 30 40 50];
            app.ParamSlider.ValueChangedFcn = createCallbackFcn(app, @ChangeParameter, true);
            app.ParamSlider.MinorTicks = [1 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30 32 34 36 38 40 42 44 46 48 50];
            app.ParamSlider.Value = 15;
            app.SegmentButton = uibutton(app.UIFigure, 'push');
            app.SegmentButton.ButtonPushedFcn = createCallbackFcn(app, @Segmentation, true);
            app.SegmentButton.Text = 'Segmentacja';
            app.UIimgSegmented = uiaxes(app.UIFigure);
            zlabel(app.UIimgSegmented, 'Z')
            app.UIimgSegmented.XTick = [];
            app.UIimgSegmented.YTickLabel = '';
            app.UIImgOriginal = uiaxes(app.UIFigure);
            zlabel(app.UIImgOriginal, 'Z')
            app.UIImgOriginal.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIImgOriginal.XTickLabel = '';
            app.UIImgOriginal.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIImgOriginal.YTickLabel = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = projekt

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end